classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        Button      matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function selectFile(app, event)
            fig = app.UIFigure;
            msg = ['Do you want to load an export with or without ...' ...
                ' manually configured samples?'];
            title = 'Choose file configuration';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'With samples','Without samples','Cancel'}, ...
           'DefaultOption',2,'CancelOption',3);
            
            if ~strcmp(selection, 'Cancel')
                [file, path] = uigetfile('*m');
                path2file = fullfile(path, file);
                if strcmp(selection, 'Without samples')
                    DataWithoutSamples = WithoutSamplesParser(path2file);
                else
                    DataWithSamples = WithoutSamplesParser(path2file);
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end