classdef createSampleFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        currentfileLabel               matlab.ui.control.Label
        setgapbetweensamplesLabel      matlab.ui.control.Label
        selectlengthofsinglesampleLabel  matlab.ui.control.Label
        selectstartminuteofsamplecreationLabel  matlab.ui.control.Label
        ConfigureyoursamplesLabel      matlab.ui.control.Label
        samplestartEditField           matlab.ui.control.NumericEditField
        samplestartEditFieldLabel      matlab.ui.control.Label
        sampleshiftEditField           matlab.ui.control.NumericEditField
        sampleshiftEditFieldLabel      matlab.ui.control.Label
        measurementlengthminEditField  matlab.ui.control.NumericEditField
        measurementlengthminEditFieldLabel  matlab.ui.control.Label
        samplesizeEditField            matlab.ui.control.NumericEditField
        samplesizeEditFieldLabel       matlab.ui.control.Label
        nofileselectedLabel            matlab.ui.control.Label
        SelectfileButton               matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingapp % mainapp
        filename
        path2file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
          % Store main app object
          app.callingapp = mainapp;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            importdata(app.callingapp, app.samplestartEditField.Value, ...
                app.samplesizeEditField.Value, ...
                app.measurementlengthminEditField.Value, ...
                app.sampleshiftEditField.Value, ...
                app.filename, app.path2file);
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)            
        end

        % Value changed function: samplesizeEditField
        function samplesizeEditFieldValueChanged(app, event)
            value = app.samplesizeEditField.Value;
            
        end

        % Value changed function: measurementlengthminEditField
        function measurementlengthminEditFieldValueChanged(app, event)
            value = app.measurementlengthminEditField.Value;
            
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file, path] = uigetfile('*.EDF');
            app.filename = file;
            app.path2file = path;
            set(app.nofileselectedLabel, 'Text', file);
            set(app.OKButton, 'Enable', 'on');
        end

        % Value changed function: sampleshiftEditField
        function sampleshiftEditFieldValueChanged(app, event)
            value = app.sampleshiftEditField.Value;
            
        end

        % Value changed function: samplestartEditField
        function samplestartEditFieldValueChanged(app, event)
            value = app.samplestartEditField.Value;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.SelectfileButton = uibutton(app.GridLayout, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.nofileselectedLabel = uilabel(app.GridLayout);
            app.nofileselectedLabel.Text = 'no file selected';
            app.samplesizeEditFieldLabel = uilabel(app.GridLayout);
            app.samplesizeEditFieldLabel.Text = 'sample size';
            app.samplesizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.samplesizeEditField.Limits = [1 Inf];
            app.samplesizeEditField.ValueChangedFcn = createCallbackFcn(app, @samplesizeEditFieldValueChanged, true);
            app.samplesizeEditField.Value = 1;
            app.measurementlengthminEditFieldLabel = uilabel(app.GridLayout);
            app.measurementlengthminEditFieldLabel.Text = 'measurement length (min)';
            app.measurementlengthminEditField = uieditfield(app.GridLayout, 'numeric');
            app.measurementlengthminEditField.Limits = [1 Inf];
            app.measurementlengthminEditField.ValueChangedFcn = createCallbackFcn(app, @measurementlengthminEditFieldValueChanged, true);
            app.measurementlengthminEditField.Value = 1;
            app.sampleshiftEditFieldLabel = uilabel(app.GridLayout);
            app.sampleshiftEditFieldLabel.Text = 'sample shift ';
            app.sampleshiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.sampleshiftEditField.Limits = [1 Inf];
            app.sampleshiftEditField.ValueChangedFcn = createCallbackFcn(app, @sampleshiftEditFieldValueChanged, true);
            app.sampleshiftEditField.Value = 1;
            app.samplestartEditFieldLabel = uilabel(app.GridLayout);
            app.samplestartEditFieldLabel.Text = 'sample start';
            app.samplestartEditField = uieditfield(app.GridLayout, 'numeric');
            app.samplestartEditField.ValueChangedFcn = createCallbackFcn(app, @samplestartEditFieldValueChanged, true);
            app.ConfigureyoursamplesLabel = uilabel(app.GridLayout);
            app.ConfigureyoursamplesLabel.Text = 'Configure your samples';
            app.selectstartminuteofsamplecreationLabel = uilabel(app.GridLayout);
            app.selectstartminuteofsamplecreationLabel.Text = '(select start minute of sample creation)';
            app.selectlengthofsinglesampleLabel = uilabel(app.GridLayout);
            app.selectlengthofsinglesampleLabel.Text = '(select length of single sample)';
            app.setgapbetweensamplesLabel = uilabel(app.GridLayout);
            app.setgapbetweensamplesLabel.Text = '(set gap between samples)';
            app.currentfileLabel = uilabel(app.GridLayout);
            app.currentfileLabel.Text = 'current file:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createSampleFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end